classdef synabr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PlotAxes                matlab.ui.control.UIAxes
        TankButton              matlab.ui.control.Button
        PlotButton              matlab.ui.control.Button
        TankEdit                matlab.ui.control.EditField
        SingleCyclesCheckBox    matlab.ui.control.CheckBox
        MeanCheckBox            matlab.ui.control.CheckBox
        DialogueLabel           matlab.ui.control.Label
        CyclesEditFieldLabel    matlab.ui.control.Label
        CyclesEditField         matlab.ui.control.EditField
        HipassHzEditFieldLabel  matlab.ui.control.Label
        HPCFEdit                matlab.ui.control.NumericEditField
        LopassHzEditFieldLabel  matlab.ui.control.Label
        LPCFEdit                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Property % Description
        data = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            raw = app.data.raw;
            fs = app.data.fs;
%             fs = 24414;
%              t = (1:round(0.02*fs))./fs;
%             raw = NaN(2,round((0.02*fs)));
%             for cycle = 1:100
%                 raw(cycle,:) = sin(2*pi*100*t) + 0.01*randn(size(t));
%             end
            
            fny = fs/2; 
            fcl = app.HPCFEdit.Value;
            fcu = app.LPCFEdit.Value;
            [b,a] = butter(2, [fcl/fny fcu/fny]);
            signals = squeeze(filter(b,a, raw'))';
            startsamp = round(app.data.evons(1:end-1)*fs);
            endsamp = startsamp + round(.02*fs);
            usechan = 1;
            if size(raw, 1)<endsamp(end)
                startsamp = startsamp(1:(end-1));
                endsamp = endsamp(1:(end-1));
            end
             for cycle = 1:numel(startsamp)
                sttrace(cycle,:) = raw(startsamp(cycle):endsamp(cycle), usechan);
             end
            t = (1:size(sttrace, 2))./fs;            
            meansig = mean(sttrace);
            plot(app.PlotAxes, 0, 0);
            hold(app.PlotAxes, 'on');
            plot(app.PlotAxes, t, sttrace, 'k');
            plot(app.PlotAxes, t, meansig, 'r');
            hold(app.PlotAxes, 'off');
        end

        % Button pushed function: TankButton
        function TankButtonPushed(app, event)
            [filepath, ~, ~] = fileparts(app.TankEdit.Value);
            if isempty(filepath)
                tankpath = uigetdir('D:\Synapse\Tanks');
            else
                tankpath = uigetdir(filepath);
            end
            if tankpath ~= 0
                app.TankEdit.Value = tankpath;
            end
            figure(app.UIFigure);
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
        %Load the tank and update gui
            out = readtank(app.TankEdit.Value);
            app.data = out;
            app.DialogueLabel.Text = 'Ready';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotAxes = uiaxes(app.UIFigure);
            title(app.PlotAxes, 'Title')
            xlabel(app.PlotAxes, 'X')
            ylabel(app.PlotAxes, 'Y')
            app.TankButton = uibutton(app.UIFigure, 'push');
            app.TankButton.ButtonPushedFcn = createCallbackFcn(app, @TankButtonPushed, true);
            app.TankButton.Text = 'Tank';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TankEdit = uieditfield(app.UIFigure, 'text');
            app.SingleCyclesCheckBox = uicheckbox(app.UIFigure);
            app.SingleCyclesCheckBox.Text = 'Single Cycles';
            app.MeanCheckBox = uicheckbox(app.UIFigure);
            app.MeanCheckBox.Text = 'Mean';
            app.DialogueLabel = uilabel(app.UIFigure);
            app.CyclesEditFieldLabel = uilabel(app.UIFigure);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.CyclesEditField = uieditfield(app.UIFigure, 'text');
            app.HipassHzEditFieldLabel = uilabel(app.UIFigure);
            app.HipassHzEditFieldLabel.Text = 'Hipass (Hz)';
            app.HPCFEdit = uieditfield(app.UIFigure, 'numeric');
            app.HPCFEdit.Value = 300;
            app.LopassHzEditFieldLabel = uilabel(app.UIFigure);
            app.LopassHzEditFieldLabel.Text = 'Lopass (Hz)';
            app.LPCFEdit = uieditfield(app.UIFigure, 'numeric');
            app.LPCFEdit.Value = 3000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synabr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end