classdef synfra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TankButton                      matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        TankEdit                        matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        PickerTab                       matlab.ui.container.Tab
        IsoIAxes                        matlab.ui.control.UIAxes
        FRAAxes                         matlab.ui.control.UIAxes
        RLFAxes                         matlab.ui.control.UIAxes
        PSTHAxes                        matlab.ui.control.UIAxes
        ResponseWindowmsEditFieldLabel  matlab.ui.control.Label
        AnalStartEdit                   matlab.ui.control.NumericEditField
        AnalEndEdit                     matlab.ui.control.NumericEditField
        SpontEndEdit                    matlab.ui.control.NumericEditField
        SpontaneousWindowmsEditFieldLabel  matlab.ui.control.Label
        SpontStartEdit                  matlab.ui.control.NumericEditField
        XChannelMapsTab                 matlab.ui.container.Tab
        AllFRAsTab                      matlab.ui.container.Tab
        MultiFRAAxes                    matlab.ui.control.UIAxes
        TuningTable                     matlab.ui.control.Table
        DialogueLabel                   matlab.ui.control.Label
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDrop                     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Property % Description
        data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TankButton
        function TankButtonPushed(app, event)
             [filepath, ~, ~] = fileparts(app.TankEdit.Value);
            if isempty(filepath)
                tankpath = uigetdir('D:\Synapse\Tanks');
            else
                tankpath = uigetdir(filepath);
            end
            if tankpath ~= 0
                app.TankEdit.Value = tankpath;
            end
            figure(app.UIFigure);
            app.DialogueLabel.Text = 'Loading Data';
            pause(.1)
            cla(app.IsoIAxes); cla(app.FRAAxes); cla(app.RLFAxes); cla(app.PSTHAxes);
            app.TuningTable.Data = NaN(32, 3);
            
        %Load the tank and update gui
            app.data = readtank(app.TankEdit.Value);
            chanlist = (1:size(app.data.raw, 2))';
            app.ChannelDrop.Items = [compose('%d', chanlist)];
            frqlist = round(sort(unique(app.data.frqs)));
            lvllist = round(sort(unique(app.data.lvls)));
%                 app.LevelDrop.Items = ['all'; compose('%d', lvllist)];
%             end

            app.DialogueLabel.Text = 'Ready';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.DialogueLabel.Text = 'Math...';
            data = app.data;
            frqlist = round(sort(unique(app.data.frqs)));
            lvllist = round(sort(unique(app.data.lvls)));
            [spetfreq, spetlevel, stimspets, trials] = freqlevelspet(data.spets, data.stimons, data.frqs, data.lvls);
%             period = mode(diff(data.stimons));
            %spike times aligned to stimulus onsets are stored in stimspets
            %The frequency and level of the last stimulus preceding each spike is stored in spetfreq and spetlevel
            analwin = [app.AnalStartEdit.Value app.AnalEndEdit.Value]'./1000; %ftc analysis window;
            spontwin = [app.SpontStartEdit.Value app.SpontEndEdit.Value]'./1000; %spontaneous analysis window;
            
%psth
            ax = app.PSTHAxes; cla(ax, 'reset');
            targetlevel = -1;
            targetfreq = -1;
            binsize = 5E-3;
            psthwindow = [-0.2 0.3];
            reference = data.stimons;
            [pst, yrange, averate, stims] = synpstbin2(data, spetfreq, targetfreq, spetlevel, targetlevel, binsize, psthwindow, reference);
            
            spontrates = findspontrate(data.spets, data.stimons, data.channels, data.chanlist, spontwin');
            totaltime = (spontwin(2)-spontwin(1))*numel(data.stimons);
            spontsig = poissinv(0.95, spontrates *binsize/(spontwin(2)-spontwin(1))); %estimate 95% CL of spikes per bin
            spontsig = spontsig./(binsize*numel(data.stimons));
            spontrates = spontrates./totaltime;
            rectangle(ax, 'Position', [stims(1) 0  stims(2) max(pst.bincount)], 'FaceColor', .8*[1 1 1], 'EdgeColor', .8*[1 1 1]);
            hold(ax, 'on');
            plot(ax, pst.bintime, pst.bincount, 'Color', [.4 .4 1], 'LineWidth', 1);
            fill(ax, spontwin([1 2 2 1]), [spontrates([1; 1]); spontsig([1; 1])], [1 .4 .4], 'FaceAlpha', .3, 'EdgeAlpha', 0);
            plot(ax, analwin, [0 0], 'Color', [.4 1 .4], 'LineWidth', 1);
            hold(ax, 'off');
            
%fra
            ax = app.FRAAxes; cla(ax, 'reset');
%             mfra = multifra(stimspets, spetfreq, spetlevel, analwin, data.channels, trials, sort(unique(data.channels)), data.frqs, data.lvls, ax);
            data.chanlist = str2double(app.ChannelDrop.Value);
            fra = squeeze(multifra(stimspets, spetfreq, spetlevel, analwin, data.channels, trials, data.chanlist, data.frqs, data.lvls, ax));
%RLF
            ax = app.RLFAxes; cla(ax, 'reset');
            vvec = [1:-1/16:0 0:1/16:1 1:-1/16:0 0:1/16:1]';
            vv = 1:32;
            colmat = [vvec(vv+32) vvec(vv+22) vvec(vv+12)];
            colmat = [colmat; 0 0 0];
            plot(ax, 0, 0);
            hold (ax, 'on');
            for frqi = 1:numel(frqlist)
                plot(ax, lvllist, fra(:,frqi), 'Color', colmat(2*frqi-1, :))
            end
            hold (ax, 'off');
            
            ax = app.IsoIAxes; cla(ax, 'reset');
            semilogx(ax, 1E4, 1);
            hold (ax, 'on');
            for lvli = 1:numel(lvllist)
                semilogx(ax, frqlist, fra(lvli,:), 'Color', colmat(3*lvli-2, :))
            end
            hold off;


            %             hold(ax, 'on')
%             plot(ax, 1:numel(unique(data.frqs)), 1+numel(unique(data.lvls)) - ftcstats(:,:,1)./10, 'wo-', 'LineWidth', 4)
%             hold(ax, 'off')
            
%             labeledfra = [];
%             fratable = table([]);
%             ftctable = table([]);
%             for chan = 1:size(fra,1)
%                 chanfra = squeeze(fra(chan,:,:));
%                 [BLi(chan, 1), BFi(chan, 1)] = ind2sub(size(chanfra), find(chanfra == max(chanfra(:)), 1));
%                 peakrate(chan,1) = max(chanfra(:));
%             end
%             for freq = 1:size(fra, 3)
%                 ftcstats(:,freq,:) = ftcthreshold(fra(:,:,freq), sort(unique(data.lvls)), spontrates, spontsig);
%             end
%             realfra = permute(fra, [2 3 1]);
%             Rate = realfra(:);
%             frachannel = repmat(data.chanlist', numel(unique(data.frqs))*numel(unique(data.lvls)), 1);
%             Channel = frachannel(:);
%             frqlist = sort(unique(data.frqs));
%             frafreq = repmat(frqlist', numel(unique(data.lvls)), numel(data.chanlist));
%             Frequency = frafreq(:);
%             lvllist = sort(unique(data.lvls));
%             Level = repmat(lvllist, numel(unique(data.frqs)) * numel(data.chanlist), 1);
%             fratable = table(Channel, Frequency, Level, Rate);
%             for chan = 1:size(fra, 1)
%                 labeledfra(chan,:,:) = [[chan; lvllist], [frqlist'; squeeze(fra(chan,:,:))]];
%             end
%             [~,CFi] = min(ftcstats(:,:,1), [], 2);
%             frqlist = sort(unique(data.frqs));
%             lvllist = sort(unique(data.lvls));
%             BL = lvllist(BLi);
%             BF = frqlist(BFi);
%             CF = frqlist(CFi);
%             allthresh = ftcstats(:,:,1);
%             thresh = diag(squeeze(ftcstats(:,CFi,1)));
%             if app.ClustsCheck.Value
%                 ftctable = table(data.channelsortorder(data.chanlist), peakrate, BF./1000, BL, CF./1000, thresh, allthresh, ...
%                     'VariableNames', {'Cluster', 'PeakRate_Hz', 'BestFreq_kHz', 'BestLevel_dB', 'CharFreq_kHz', 'Thresh_CF_dB', 'AllThresh'});
%             else
%                 ftctable = table(data.chanlist, peakrate, BF./1000, BL, CF./1000, thresh, allthresh, ...
%                     'VariableNames', {'Channel', 'PeakRate_Hz', 'BestFreq_kHz', 'BestLevel_dB', 'CharFreq_kHz', 'Thresh_CF_dB', 'AllThresh'});
%             end
%             for i = 1:numel(frqlist)
%                 threshnames{i} = ['Thresh_' num2str(round(frqlist(i)./1000)), 'kHz_dB'];
%             end
%             ftctable= splitvars(ftctable, 'AllThresh', 'NewVariableNames', threshnames);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TankButton = uibutton(app.UIFigure, 'push');
            app.TankButton.ButtonPushedFcn = createCallbackFcn(app, @TankButtonPushed, true);
            app.TankButton.Text = 'Tank';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TankEdit = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PickerTab = uitab(app.TabGroup);
            app.PickerTab.Title = 'Picker';
            app.IsoIAxes = uiaxes(app.PickerTab);
            title(app.IsoIAxes, 'Title')
            xlabel(app.IsoIAxes, 'X')
            ylabel(app.IsoIAxes, 'Y')
            app.FRAAxes = uiaxes(app.PickerTab);
            title(app.FRAAxes, 'Title')
            xlabel(app.FRAAxes, 'X')
            ylabel(app.FRAAxes, 'Y')
            app.RLFAxes = uiaxes(app.PickerTab);
            title(app.RLFAxes, 'Title')
            xlabel(app.RLFAxes, 'X')
            ylabel(app.RLFAxes, 'Y')
            app.PSTHAxes = uiaxes(app.PickerTab);
            title(app.PSTHAxes, 'Title')
            xlabel(app.PSTHAxes, 'X')
            ylabel(app.PSTHAxes, 'Y')
            app.ResponseWindowmsEditFieldLabel = uilabel(app.PickerTab);
            app.ResponseWindowmsEditFieldLabel.Text = 'Response Window (ms)';
            app.AnalStartEdit = uieditfield(app.PickerTab, 'numeric');
            app.AnalEndEdit = uieditfield(app.PickerTab, 'numeric');
            app.AnalEndEdit.Value = 100;
            app.SpontEndEdit = uieditfield(app.PickerTab, 'numeric');
            app.SpontaneousWindowmsEditFieldLabel = uilabel(app.PickerTab);
            app.SpontaneousWindowmsEditFieldLabel.Text = 'Spontaneous Window (ms)';
            app.SpontStartEdit = uieditfield(app.PickerTab, 'numeric');
            app.SpontStartEdit.Value = -200;
            app.XChannelMapsTab = uitab(app.TabGroup);
            app.XChannelMapsTab.Title = 'X-Channel Maps';
            app.AllFRAsTab = uitab(app.TabGroup);
            app.AllFRAsTab.Title = 'All FRAs';
            app.MultiFRAAxes = uiaxes(app.AllFRAsTab);
            title(app.MultiFRAAxes, 'Title')
            xlabel(app.MultiFRAAxes, 'X')
            ylabel(app.MultiFRAAxes, 'Y')
            app.TuningTable = uitable(app.UIFigure);
            app.TuningTable.ColumnName = {'Channel'; 'UserBF'; 'UserCF'; 'Column 4'};
            app.TuningTable.RowName = {};
            app.DialogueLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDrop = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synfra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end